classdef IDP_MMG_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IdentificationDesProcessusTravauxPratiquesUIFigure  matlab.ui.Figure
        MthodedesMomentsGnralissPanel  matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        LECTURETab                     matlab.ui.container.Tab
        TypeModleButtonGroup           matlab.ui.container.ButtonGroup
        FormePolynomialeButton         matlab.ui.control.RadioButton
        STREJCsansRetardButton         matlab.ui.control.RadioButton
        PanelPara                      matlab.ui.container.Panel
        EditField_13                   matlab.ui.control.EditField
        TempsFinLabel                  matlab.ui.control.Label
        EditField_14                   matlab.ui.control.EditField
        DcalageLabel                   matlab.ui.control.Label
        Spinnertf                      matlab.ui.control.Spinner
        Spinnera                       matlab.ui.control.Spinner
        PanelSR                        matlab.ui.container.Panel
        EditAmp                        matlab.ui.control.NumericEditField
        EditTem                        matlab.ui.control.NumericEditField
        EditOrd                        matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.EditField
        AmplitudeLabel                 matlab.ui.control.Label
        EditField_8                    matlab.ui.control.EditField
        OrdreLabel                     matlab.ui.control.Label
        EditField_9                    matlab.ui.control.EditField
        TempsLabel                     matlab.ui.control.Label
        EditField_10                   matlab.ui.control.EditField
        RetardLabel                    matlab.ui.control.Label
        EditRet                        matlab.ui.control.NumericEditField
        PanelFP                        matlab.ui.container.Panel
        EditField_15                   matlab.ui.control.EditField
        NumrateurLabel                 matlab.ui.control.Label
        EditField_16                   matlab.ui.control.EditField
        DnominateurLabel               matlab.ui.control.Label
        EditNum                        matlab.ui.control.EditField
        EditDen                        matlab.ui.control.EditField
        ModlePrdfiniDropDownLabel      matlab.ui.control.Label
        ModlePrdfiniDropDown           matlab.ui.control.DropDown
        LIREButton                     matlab.ui.control.Button
        GsLabel                        matlab.ui.control.Label
        LabelG                         matlab.ui.control.Label
        GmsLabel                       matlab.ui.control.Label
        LabelGM                        matlab.ui.control.Label
        SIMULATIONTab                  matlab.ui.container.Tab
        EditU                          matlab.ui.control.EditField
        LIREU                          matlab.ui.control.Button
        UtLabel                        matlab.ui.control.Label
        AxesU1                         matlab.ui.control.UIAxes
        AxesU2                         matlab.ui.control.UIAxes
        Poids                          matlab.ui.control.CheckBox
        IDENTIFICATIONTab              matlab.ui.container.Tab
        TypeRponseButtonGroup          matlab.ui.container.ButtonGroup
        ImpulsionnelleButton           matlab.ui.control.RadioButton
        IndicielleButton               matlab.ui.control.RadioButton
        AxesU                          matlab.ui.control.UIAxes
        EditField_18                   matlab.ui.control.EditField
        ErreurLabel                    matlab.ui.control.Label
        EditErr                        matlab.ui.control.NumericEditField
        Moments                        matlab.ui.control.DropDown
        IDENTIFIER                     matlab.ui.control.Button
        EditMom                        matlab.ui.control.NumericEditField
    end

%////////////////////////////////////////////////////////////////////////////////////////%
    properties (Access = private)
        TF % Transfer Function
        TFt % Transfer Function type
        a=2; % Décalage
        t_fin=25; % Temps Finale
        U % ENTRÉE
        Y % SORTIE
        Mom % MOMENTS
    end                 % Méthode des moments généralisés --- Avec mon binome câsse-pied %
%////////////////////////////////////////////////////////////////////////////////////////%

    methods (Access = private)

        % Selection changed function: TypeModleButtonGroup
        function TypeModleButtonGroupSelectionChanged(app, event)
            if app.FormePolynomialeButton.Value
            app.PanelFP.Visible='on';
            end
            if app.STREJCsansRetardButton.Value
            app.PanelFP.Visible='off';
            end
        end

        % Callback function
        function EditNumValueChanged(app, event)
        % JE N'ARRIVE PAS A ME DEBARRASER DE CE CALLBACK %
        end

        % Callback function
        function EditDenValueChanged(app, event)
        % JE N'ARRIVE PAS A ME DEBARRASER DE CE CALLBACK %
        end

        % Callback function
        function EditTfValueChanged(app, event)
        % JE N'ARRIVE PAS A ME DEBARRASER DE CE CALLBACK %
        end

        % Button pushed function: LIREButton
        function LIREButtonPushed(app, event)
            if strcmp(app.ModlePrdfiniDropDown.Value,'(Aucun)')
                if app.FormePolynomialeButton.Value
                    Num=str2num(app.EditNum.Value);
                    Den=str2num(app.EditDen.Value);
                    if any(Den~=0)
                        app.TF=tf(Num,Den);
                        app.TFt=1;
                        app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
                    end
                end
                if app.STREJCsansRetardButton.Value
                    A=app.EditAmp.Value;
                    n=app.EditOrd.Value;
                    T=app.EditTem.Value;
                    Tau=app.EditRet.Value;
                    if T~=0 || n==0
                        app.TF=zpk([],repmat(-1/T,[1 n]),A/T^n,'DisplayFormat','Time Constant','InputDelay',Tau);
                        app.TFt=2;
                        app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
                    end
                end
            end
            if strcmp(app.ModlePrdfiniDropDown.Value,'Système d''Ordre 1')
                app.TF=tf(1,[2 3]);
                app.TFt=1;
                app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
            end
            if strcmp(app.ModlePrdfiniDropDown.Value,'Système d''Ordre 2')
                app.TF=tf([2 1],[1 1 5]);
                app.TFt=1;
                app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
            end
            if strcmp(app.ModlePrdfiniDropDown.Value,'STREJC d''Ordre 3')
                app.TF=zpk([],repmat(-1/(0.4),[1 3]),4/(0.4)^3,'DisplayFormat','Time Constant','InputDelay',0.2);
                app.TFt=2;
                app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
            end
            if strcmp(app.ModlePrdfiniDropDown.Value,'STREJC d''Ordre 4')
                app.TF=zpk([],repmat(-1/2,[1 4]),2/2^4,'DisplayFormat','Time Constant','InputDelay',2);
                app.TFt=2;
                app.LabelG.Text=fonctions.TransferFunction_text(app.TF);
            end
            app.LabelGM.Text='  ?';
        end

        % Button pushed function: LIREU
        function LIREUButtonPushed(app, event)
            t=linspace(0,app.t_fin,1001);
            
            eval(['u(:,1)=',app.EditU.Value,';']);
            app.U=exp(-app.a*t').*u;
            y=lsim(app.TF,u,t);
            app.Y=exp(-app.a*t').*y;
            
            if not(app.Poids.Value)
                cla(app.AxesU1)
                cla(app.AxesU2)
                title(app.AxesU1,'Entr{\''e}e du syst{\`e}me {\`a} identifier','interpret','latex','Fontsize',14);
                plot(app.AxesU1,t,exp(-app.a*t'),'--r','linewidth',.01)
                hold(app.AxesU1);
                plot(app.AxesU1,t,u,'linewidth',1.1)
                legend(app.AxesU1,{'$$u_e=f(t)$$','$$e^{-a.t}$$'},'Location','best','Interpreter','latex','Fontsize',10);
                
                title(app.AxesU2,'Sortie du syst{\`e}me {\`a} identifier','interpret','latex','Fontsize',14)
                plot(app.AxesU2,t,exp(-app.a*t'),'--r','linewidth',.01)
                hold(app.AxesU2);
                plot(app.AxesU2,t,y,'linewidth',1.1)
                legend(app.AxesU2,{'$$y_s=g(t).e^{-a.t}$$','$$e^{-a.t}$$'},'Location','best','Interpreter','latex','Fontsize',10);
                
                hold(app.AxesU1);
                hold(app.AxesU2);
                clc
                
            else
                cla(app.AxesU1)
                cla(app.AxesU2)
                title(app.AxesU1,'Entr{\''e}e du syst{\`e}me {\`a} identifier apr{\''e}s ajout des poids','interpret','latex','Fontsize',14)
                plot(app.AxesU1,t,app.U,'linewidth',1)
                legend(app.AxesU1,{'$$u_e=f(t).e^{-a.t}$$'},'Location','best','Interpreter','latex','Fontsize',10);
                
                title(app.AxesU2,'Sortie du syst{\`e}me {\`a} identifier apr{\''e}s ajout des poids','interpret','latex','Fontsize',14)
                plot(app.AxesU2,t,app.Y,'linewidth',1)
                legend(app.AxesU2,{'$$y_s=g(t).e^{-a.t}$$'},'Location','best','Interpreter','latex','Fontsize',10);
                
            end
        end

        % Value changed function: Spinnera
        function SpinneraValueChanged(app, event)
            app.a = app.Spinnera.Value;
        end

        % Value changed function: Spinnertf
        function SpinnertfValueChanged(app, event)
            app.t_fin = app.Spinnertf.Value;
        end

        % Button pushed function: IDENTIFIER
        function IDENTIFIERButtonPushed(app, event)
            cla(app.AxesU)
            t=linspace(0,app.t_fin,1001);
            if app.TFt==1
                N=order(app.TF);
                Me=fonctions.CalcMoment(2*N,t,app.U);
                Ms=fonctions.CalcMoment(2*N,t,app.Y);
                M =fonctions.CalcMomentGen(Me,Ms);
                app.Mom=M;
                D=fonctions.CalcParamPolynome(M,N,app.a);
                D=fliplr(D');
                Gm=tf(D(N+1:end),[1 D(1:N)]);
                app.LabelGM.Text=fonctions.TransferFunction_text(Gm);
            end
            if app.TFt==2
                N=4;
                Me=fonctions.CalcMoment(N,t,app.U);      %Moment de l'entrée
                Ms=fonctions.CalcMoment(N,t,app.Y);      %Moment de la sortie
                M =fonctions.CalcMomentGen(Me,Ms);   %Moment impulsionnel
                app.Mom=M;
                m =[M(1) M(2:end)./M(1)];
                
                %calcul des paramètres
                T_0=(2*m(2)^3 - 3*m(3)*m(2) + m(4))/(- 2*m(2)^2 + 2*m(3));
                T=T_0/(1-app.a*T_0);
                Tau=(m(2)^2*m(3) + m(4)*m(2) - 2*m(3)^2)/(2*m(2)^3 - 3*m(3)*m(2) + m(4));
                n=round((4*(-m(2)^2 + m(3))^3)/(2*m(2)^3 - 3*m(3)*m(2) + m(4))^2);
                K=m(1)*(1+app.a*T)^n/(exp(-app.a*Tau));
                Gm=zpk([],repmat(-1/T,[1 n]),K/T^n,'DisplayFormat','Time Constant','InputDelay',Tau);
                app.LabelGM.Text=fonctions.TransferFunction_text(Gm);
            end
            N=length(M);
            C=cell(1,N);
            for i=1:N
                C{i}=['Moment d''Ordre ',num2str(i-1)];
            end
            app.Moments.Items=C;
            app.Moments.Value=C{1};
            app.EditMom.Value=M(1);
            
            if app.ImpulsionnelleButton.Value
                ym=impulse(Gm,t);
                y=impulse(app.TF,t);
                app.EditErr.Value=fonctions.IntSimpson((y-ym).^2,t(2));
                title(app.AxesU,'R{\''e}ponse Impulsionnelle ( Syst{\`e}me + Mod{\`e}le )','interpret','latex','Fontsize',14)
                hold(app.AxesU)
                plot(app.AxesU,t,y)
                plot(app.AxesU,t,ym,'r--','linewidth',1.1)
                hold(app.AxesU)
                legend(app.AxesU,{'$$y(t)$$','$$y_m(t)$$'},'Interpreter','latex','Location','best','Fontsize',12);
            end
            if app.IndicielleButton.Value
                ym=step(Gm,t);
                y=step(app.TF,t);
                app.EditErr.Value=fonctions.IntSimpson((y-ym).^2,t(2));
                title(app.AxesU,'R{\''e}ponse indicielle ( Syst{\`e}me + Mod{\`e}le )','interpret','latex','Fontsize',14)
                hold(app.AxesU)
                plot(app.AxesU,t,y)
                plot(app.AxesU,t,ym,'r--','linewidth',1.1)
                hold(app.AxesU)
                legend(app.AxesU,{'$$y(t)$$','$$y_m(t)$$'},'Interpreter','latex','Location','best','Fontsize',12);
            end
            clc
        end

        % Value changed function: Moments
        function MomentsValueChanged(app, event)
            C = app.Moments.Value;
            app.EditMom.Value=app.Mom(1+str2num(C(16:end)));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IdentificationDesProcessusTravauxPratiquesUIFigure = uifigure;
            app.IdentificationDesProcessusTravauxPratiquesUIFigure.Name = 'Identification Des Processus : Travaux Pratiques';
            app.MthodedesMomentsGnralissPanel = uipanel(app.IdentificationDesProcessusTravauxPratiquesUIFigure);
            app.MthodedesMomentsGnralissPanel.Title = 'Méthode des Moments Généralisés';
            app.TabGroup = uitabgroup(app.MthodedesMomentsGnralissPanel);
            app.LECTURETab = uitab(app.TabGroup);
            app.LECTURETab.Title = '  LECTURE';
            app.TypeModleButtonGroup = uibuttongroup(app.LECTURETab);
            app.TypeModleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeModleButtonGroupSelectionChanged, true);
            app.TypeModleButtonGroup.Title = 'Type Modèle';
            app.FormePolynomialeButton = uiradiobutton(app.TypeModleButtonGroup);
            app.FormePolynomialeButton.Text = 'Forme Polynomiale';
            app.FormePolynomialeButton.Value = true;
            app.STREJCsansRetardButton = uiradiobutton(app.TypeModleButtonGroup);
            app.STREJCsansRetardButton.Text = 'STREJC sans Retard';
            app.PanelPara = uipanel(app.LECTURETab);
            app.PanelPara.Title = 'Paramètres';
            app.EditField_13 = uieditfield(app.PanelPara, 'text');
            app.EditField_13.Editable = 'off';
            app.TempsFinLabel = uilabel(app.PanelPara);
            app.TempsFinLabel.Text = 'Temps Fin :';
            app.EditField_14 = uieditfield(app.PanelPara, 'text');
            app.EditField_14.Editable = 'off';
            app.DcalageLabel = uilabel(app.PanelPara);
            app.DcalageLabel.Text = 'Décalage :';
            app.Spinnertf = uispinner(app.PanelPara);
            app.Spinnertf.Step = 5;
            app.Spinnertf.Limits = [0 Inf];
            app.Spinnertf.ValueDisplayFormat = '%5.2g';
            app.Spinnertf.ValueChangedFcn = createCallbackFcn(app, @SpinnertfValueChanged, true);
            app.Spinnertf.Value = 25;
            app.Spinnera = uispinner(app.PanelPara);
            app.Spinnera.Limits = [0 Inf];
            app.Spinnera.ValueDisplayFormat = '%4.2g';
            app.Spinnera.ValueChangedFcn = createCallbackFcn(app, @SpinneraValueChanged, true);
            app.Spinnera.Value = 2;
            app.PanelSR = uipanel(app.LECTURETab);
            app.EditAmp = uieditfield(app.PanelSR, 'numeric');
            app.EditAmp.ValueDisplayFormat = '%4.2g';
            app.EditTem = uieditfield(app.PanelSR, 'numeric');
            app.EditTem.ValueDisplayFormat = '%4.2g';
            app.EditOrd = uieditfield(app.PanelSR, 'numeric');
            app.EditOrd.ValueDisplayFormat = '%.0f';
            app.EditField_7 = uieditfield(app.PanelSR, 'text');
            app.EditField_7.Editable = 'off';
            app.AmplitudeLabel = uilabel(app.PanelSR);
            app.AmplitudeLabel.Text = 'Amplitude :';
            app.EditField_8 = uieditfield(app.PanelSR, 'text');
            app.EditField_8.Editable = 'off';
            app.OrdreLabel = uilabel(app.PanelSR);
            app.OrdreLabel.Text = 'Ordre :';
            app.EditField_9 = uieditfield(app.PanelSR, 'text');
            app.EditField_9.Editable = 'off';
            app.TempsLabel = uilabel(app.PanelSR);
            app.TempsLabel.Text = 'Temps :';
            app.EditField_10 = uieditfield(app.PanelSR, 'text');
            app.EditField_10.Editable = 'off';
            app.RetardLabel = uilabel(app.PanelSR);
            app.RetardLabel.Text = 'Retard :';
            app.EditRet = uieditfield(app.PanelSR, 'numeric');
            app.EditRet.ValueDisplayFormat = '%4.2g';
            app.PanelFP = uipanel(app.LECTURETab);
            app.EditField_15 = uieditfield(app.PanelFP, 'text');
            app.EditField_15.Editable = 'off';
            app.NumrateurLabel = uilabel(app.PanelFP);
            app.NumrateurLabel.Text = 'Numérateur :';
            app.EditField_16 = uieditfield(app.PanelFP, 'text');
            app.EditField_16.Editable = 'off';
            app.DnominateurLabel = uilabel(app.PanelFP);
            app.DnominateurLabel.Text = 'Dénominateur :';
            app.EditNum = uieditfield(app.PanelFP, 'text');
            app.EditNum.Value = '[ 0 ]';
            app.EditDen = uieditfield(app.PanelFP, 'text');
            app.EditDen.Value = '[ 0 ]';
            app.ModlePrdfiniDropDownLabel = uilabel(app.LECTURETab);
            app.ModlePrdfiniDropDownLabel.Text = 'Modèle Prédéfini :';
            app.ModlePrdfiniDropDown = uidropdown(app.LECTURETab);
            app.ModlePrdfiniDropDown.Items = {'(Aucun)', 'Système d''Ordre 1', 'Système d''Ordre 2', 'STREJC d''Ordre 3', 'STREJC d''Ordre 4'};
            app.ModlePrdfiniDropDown.Value = '(Aucun)';
            app.LIREButton = uibutton(app.LECTURETab, 'push');
            app.LIREButton.ButtonPushedFcn = createCallbackFcn(app, @LIREButtonPushed, true);
            app.LIREButton.Text = 'LIRE !';
            app.GsLabel = uilabel(app.LECTURETab);
            app.GsLabel.Text = 'G(s)=';
            app.LabelG = uilabel(app.LECTURETab);
            app.LabelG.Text = '  0';
            app.GmsLabel = uilabel(app.LECTURETab);
            app.GmsLabel.Text = 'Gm(s)=';
            app.LabelGM = uilabel(app.LECTURETab);
            app.LabelGM.Text = '  ?';
            app.SIMULATIONTab = uitab(app.TabGroup);
            app.SIMULATIONTab.Title = '  SIMULATION';
            app.EditU = uieditfield(app.SIMULATIONTab, 'text');
            app.EditU.Value = 'min(exp(t-5),exp(-t+5))-exp(-5)';
            app.LIREU = uibutton(app.SIMULATIONTab, 'push');
            app.LIREU.ButtonPushedFcn = createCallbackFcn(app, @LIREUButtonPushed, true);
            app.LIREU.Text = 'SIMULER !';
            app.UtLabel = uilabel(app.SIMULATIONTab);
            app.UtLabel.Text = 'U(t)=';
            app.AxesU1 = uiaxes(app.SIMULATIONTab);
            title(app.AxesU1, ' ')
            ylabel(app.AxesU1, 'Input')
            app.AxesU2 = uiaxes(app.SIMULATIONTab);
            title(app.AxesU2, ' ')
            xlabel(app.AxesU2, 'Temps (s)')
            ylabel(app.AxesU2, 'Output')
            app.Poids = uicheckbox(app.SIMULATIONTab);
            app.Poids.Text = 'Poids';
            app.IDENTIFICATIONTab = uitab(app.TabGroup);
            app.IDENTIFICATIONTab.Title = '  IDENTIFICATION';
            app.TypeRponseButtonGroup = uibuttongroup(app.IDENTIFICATIONTab);
            app.TypeRponseButtonGroup.Title = 'Type Réponse';
            app.ImpulsionnelleButton = uiradiobutton(app.TypeRponseButtonGroup);
            app.ImpulsionnelleButton.Text = 'Impulsionnelle';
            app.ImpulsionnelleButton.Value = true;
            app.IndicielleButton = uiradiobutton(app.TypeRponseButtonGroup);
            app.IndicielleButton.Text = 'Indicielle';
            app.AxesU = uiaxes(app.IDENTIFICATIONTab);
            title(app.AxesU, ' ')
            xlabel(app.AxesU, 'Temps (s)')
            app.EditField_18 = uieditfield(app.IDENTIFICATIONTab, 'text');
            app.EditField_18.Editable = 'off';
            app.ErreurLabel = uilabel(app.IDENTIFICATIONTab);
            app.ErreurLabel.Text = 'Erreur :';
            app.EditErr = uieditfield(app.IDENTIFICATIONTab, 'numeric');
            app.EditErr.Editable = 'off';
            app.Moments = uidropdown(app.IDENTIFICATIONTab);
            app.Moments.Items = {'Moments'};
            app.Moments.ValueChangedFcn = createCallbackFcn(app, @MomentsValueChanged, true);
            app.Moments.Value = 'Moments';
            app.IDENTIFIER = uibutton(app.IDENTIFICATIONTab, 'push');
            app.IDENTIFIER.ButtonPushedFcn = createCallbackFcn(app, @IDENTIFIERButtonPushed, true);
            app.IDENTIFIER.Text = 'IDENTIFIER !';
            app.EditMom = uieditfield(app.IDENTIFICATIONTab, 'numeric');
            app.EditMom.ValueDisplayFormat = '%2.4g';
            app.EditMom.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IDP_MMG_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IdentificationDesProcessusTravauxPratiquesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IdentificationDesProcessusTravauxPratiquesUIFigure)
        end
    end
end